classdef outline1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        changeText         matlab.ui.control.EditField
        moneyText          matlab.ui.control.EditField
        outputSlot         matlab.ui.control.Button
        inputSlot          matlab.ui.control.Button
        Frame_2            matlab.ui.control.Image
        coffeeSlot         matlab.ui.control.Image
        coffeeSlot2        matlab.ui.control.Image
        maintenanceButton  matlab.ui.control.Button
        sugarCount         matlab.ui.control.NumericEditField
        change             matlab.ui.control.NumericEditField
        EditField          matlab.ui.control.EditField
        Welcome            matlab.ui.control.Image
        Frame              matlab.ui.control.Image
        money              matlab.ui.control.NumericEditField
        KF10               matlab.ui.control.Image
        KF50               matlab.ui.control.Image
        KM1                matlab.ui.control.Image
        KM2                matlab.ui.control.Image
        KM5                matlab.ui.control.Image
        KF20               matlab.ui.control.Image
        Lamp               matlab.ui.control.Lamp
        EditField_2        matlab.ui.control.EditField
        ButtonSugar        matlab.ui.control.Button
        ButtonNoSugar      matlab.ui.control.Button
        ConfirmButton      matlab.ui.control.Button
        DoppioButton       matlab.ui.control.Button
        CortadoButton      matlab.ui.control.Button
        FlatWhiteButton    matlab.ui.control.Button
        MacchiatoButton    matlab.ui.control.Button
        CappuccinoButton   matlab.ui.control.Button
        EspressoButton     matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

properties (Access = private)
        
        %[coffee water milk sugar price]; the order of the variables of one row
    
            machineState=[1000 1000 1000 1000 100]; %what resources the machine has
            
            coffeeResourcesNeeded =[10 30 0 0 1; %espresso
            
                                   10 30 100 0 2; %cappuccino
                                    
                                   10 30 20 0 2; %macciato
                                    
                                   20 60 0 0 2.5; %doppio
                                    
                                   10 30 100 0 3; %flat white
                                    
                                   10 30 30 0 3;]; %cortado
    
    
            coffeeSelected = 0; % the index of the coffee selected
            stock=1; % if there are sufficient resources in the machine, stock=1
            made=0; % if the coffee was made and the resources were deducted from the machine, made=1
            paid=0; % if the customer paied for the coffee selected, paid=1
    end
    
    methods (Access = private)
        
        %the function adds a sound to a button/coin/video when the function
        %is called
        function addSound(app,sounds)
               [y,fs]=audioread(sounds);
               sound(y,fs);
        end

        %when a coffee button pressed, an image of the coffee is shown and the user is asked if they want to select it
        function coffeeSelection(app,a)
                img=imread(a);
                image(img);
                imshow(img,'parent', app.UIAxes);
                app.Welcome.Visible="off";
                app.EditField.Value='Would you like to select this drink?';        
        end
        
        %checks if any resource is out of stock, and turns the lamp red if it
        %is
        function outOfStock(app,coffee)
                for i=1:4
                    if app.machineState(1,i) < app.coffeeResourcesNeeded(coffee,i)
                        app.stock=0;
                        app.Lamp.Color='r';
                        app.maintenanceButton.Visible='on';
                    end
                end
                
        end
        
        %deducts the resources needed to make the coffee from the machine
        function makeCoffee(app, coffee)
                outOfStock(app, coffee);
                if(app.stock==0)
                       app.EditField.Value='OUT OF STOCK';
                       app.change.Value=app.money.Value;
                       app.money.Value=0;
                       moneyOutputAnimation(app);
                       addCoinSound(app);
                else
                    for i=1:4
                       app.machineState(1,i)=app.machineState(1,i) - app.coffeeResourcesNeeded(coffee,i);
                    end
                    app.made=1;
                end
        end
        
        %checks if enough money was entered and calculates the change
        function coffeePaid(app, coffee)
                 if  app.money.Value==0 %checks if any money was entered
                     app.EditField.Value='Please enter the money!';
                 elseif app.money.Value<=app.machineState(5) %then checks if the machine can give change (if someone enters a huge number the)
                     if app.money.Value<app.coffeeResourcesNeeded(coffee,5) %cheks if enough money was entered
                         app.paid=0;
                         app.EditField.Value='Not enough money!';
                     else
                         app.machineState(5)= app.machineState(5)+app.coffeeResourcesNeeded(app.coffeeSelected,5);
                         app.paid=1;
                         if app.money.Value>app.coffeeResourcesNeeded(coffee,5)
                             moneyOutputAnimation(app);
                             addSound(app,'./sounds/coinSound.wav');
                         end
                         app.change.Value=app.money.Value-app.coffeeResourcesNeeded(coffee,5);
                     end
                 elseif app.money.Value>app.machineState(5)
                 %special case: large sum of money was entered and the machine cannot give the needed change, so it just returns the money            
                       app.change.Value=app.money.Value;
                       app.money.Value=0;
                       app.EditField.Value='Cannot process the payment.';
                       moneyOutputAnimation(app);
                       addSound(app,'./sounds/coinSound.wav');
                 end
        end
              
        %restarts the state (except the resources) when a coffee is made
        function restartMachine(app)
                 app.coffeeSelected = 0;
                 app.made=0;
                 app.paid=0;
                 app.money.Value=0;
                 app.change.Value=0;
                 app.Welcome.Visible="on";
                 img=imread('./coffeeImages/background.png');
                 image(img);
                 imshow(img,'parent', app.UIAxes);
                 app.EditField.Value='Welcome!';
                 app.maintenanceButton.Visible='off';
                 app.sugarCount.Value=0;
                 app.coffeeSlot.Visible='on';
    
                 app.MacchiatoButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.MacchiatoButton.FontColor='w';
    
                 app.CappuccinoButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.CappuccinoButton.FontColor='w';
    
                 app.EspressoButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.EspressoButton.FontColor='w';
    
                 app.DoppioButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.DoppioButton.FontColor='w';
    
                 app.FlatWhiteButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.FlatWhiteButton.FontColor='w';
    
                 app.CortadoButton.BackgroundColor=[0.42, 0.40, 0.40];
                 app.CortadoButton.FontColor='w';
        end
                
        %the button changes the colour if it is selected and other buttons are
        %changed to default
        function buttonPressed(app, button, button1, button2, button3, button4, button5, event)
                button.FontColor='k';
                button.BackgroundColor=[1.00, 0.00, 0.62];
    
                button1.FontColor='w';
                button1.BackgroundColor=[0.42, 0.40, 0.40];
    
                button2.FontColor='w';
                button2.BackgroundColor=[0.42, 0.40, 0.40];
    
                button3.FontColor='w';
                button3.BackgroundColor=[0.42, 0.40, 0.40];
    
                button4.FontColor='w';
                button4.BackgroundColor=[0.42, 0.40, 0.40];
    
                button5.FontColor='w';
                button5.BackgroundColor=[0.42, 0.40, 0.40];               
        end

        %increments the amount of sugar by 1 if sugar<5 and the machine has
        %enough resources
        function moreSugar(app)
            if app.sugarCount.Value<5 && app.machineState(1,4)>=5
                app.sugarCount.Value=app.sugarCount.Value+1;
                app.machineState(1,4)=app.machineState(1,4)-5;
            end
        end

       %decrements the amount of sugar by 1 if sugar>0 and returns the
       %resources to the machine
        function lessSugar(app)
            if(app.sugarCount.Value>0)
                app.sugarCount.Value=app.sugarCount.Value-1;         
                app.machineState(1,4)=app.machineState(1,4)+5;
            end
        end
        
        %displaying an animation of a coin entering the machine
        function moneyInputAnimation(app)
            app.inputSlot.Icon='./moneyImages/moneyAnimation.gif';
            pause(1);
            app.inputSlot.Icon='./moneyImages/moneyInput.gif';
        end

       %displaying an animation of change being given
        function moneyOutputAnimation(app)
            app.outputSlot.Icon='./moneyImages/changeAnimation.gif';
            pause(1);
            app.outputSlot.Icon='./moneyImages/changeOutput.gif';
        end

        %show a video of a coffe being made
        function coffeeMakeVideo(app)
            v = VideoReader("coffeeVideo/coffeeVideo.mp4");
            app.coffeeSlot2.Visible='off';
            app.coffeeSlot.Visible='off';
            addSound(app,'./sounds/coffeeMakeAudio.mp3');
            for i=1:2
                v.CurrentTime = 0.0;
                while hasFrame(v)
                    vidFrame = readFrame(v);
                    image(vidFrame,"Parent",app.UIAxes2)
                    pause(1/v.FrameRate)
                end
            end
            clear v;
            app.coffeeSlot2.Visible='on';
        end
end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EspressoButton
        function EspressoButtonPushed(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/espresso.png'); 
            app.coffeeSelected=1;
            buttonPressed(app, app.EspressoButton, app.CappuccinoButton, app.MacchiatoButton, app.DoppioButton, app.FlatWhiteButton, app.CortadoButton);
        end

        % Button pushed function: CappuccinoButton
        function Cappuchino(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/cappuccino.png');
            app.coffeeSelected=2;
            buttonPressed(app, app.CappuccinoButton,  app.EspressoButton, app.MacchiatoButton, app.DoppioButton, app.FlatWhiteButton, app.CortadoButton);
        end

        % Button pushed function: MacchiatoButton
        function macchiato(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/macchiato.png');
            app.coffeeSelected=3;      
            buttonPressed(app, app.MacchiatoButton, app.CappuccinoButton, app.EspressoButton, app.DoppioButton, app.FlatWhiteButton, app.CortadoButton);
        end

        % Button pushed function: DoppioButton
        function doppio(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/doppio.png');
            app.coffeeSelected=4;  
            buttonPressed(app, app.DoppioButton, app.CappuccinoButton, app.MacchiatoButton, app.EspressoButton, app.FlatWhiteButton, app.CortadoButton);
        end

        % Button pushed function: FlatWhiteButton
        function flatwhite(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/flatWhite.png');
            app.coffeeSelected=5;
            buttonPressed(app, app.FlatWhiteButton, app.CappuccinoButton, app.MacchiatoButton, app.DoppioButton, app.EspressoButton, app.CortadoButton);
        end

        % Button pushed function: CortadoButton
        function cortado(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            coffeeSelection(app,'./coffeeImages/cortado.png');
            app.coffeeSelected=6;
            buttonPressed(app, app.CortadoButton, app.CappuccinoButton, app.MacchiatoButton, app.DoppioButton, app.FlatWhiteButton, app.EspressoButton);
        end

        % Button pushed function: ConfirmButton
        function confirm(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            if app.coffeeSelected==0
                 app.EditField.Value='Select a coffee!';
            else
                 coffeePaid(app, app.coffeeSelected);
            end
            if app.money.Value>0 && app.paid==1 && app.coffeeSelected~=0
               makeCoffee(app, app.coffeeSelected);
               if (app.made==1)
                   app.EditField.Value='Your coffee will be ready soon!';
                   coffeeMakeVideo(app);
                   pause(1/2);
                   app.EditField.Value='Your coffee is ready!';
                   pause(3);
                   restartMachine(app);
               end
            end
        end

        % Button pushed function: ButtonSugar
        function addSugar(app, event)
            addSound(app,'./sounds/buttonSound.mp3');
            moreSugar(app);
        end

        % Button pushed function: ButtonNoSugar
        function noSugar(app, event)
             addSound(app,'./sounds/buttonSound.mp3');
             lessSugar(app);
        end

        % Image clicked function: KF10
        function kf10(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+0.10; %adding money to display
            moneyInputAnimation(app);
        end

        % Image clicked function: KF20
        function kf20(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+0.20; %adding money to display
            moneyInputAnimation(app);
        end

        % Image clicked function: KF50
        function kf50(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+0.50; %adding money to display
            moneyInputAnimation(app);
        end

        % Image clicked function: KM1
        function km1(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+1; %adding money to display
            moneyInputAnimation(app);
        end

        % Image clicked function: KM2
        function km2(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+2; %adding money to display
            moneyInputAnimation(app);
        end

        % Image clicked function: KM5
        function km5(app, event)
            addSound(app,'./sounds/coinSound.wav');
            app.money.Value=app.money.Value+5; %adding money to display
            moneyInputAnimation(app);
        end

        % Button pushed function: maintenanceButton
        function maintenance(app, event)
           addSound(app,'./sounds/buttonSound.mp3');
           restartMachine(app);
           app.stock=1;
           for i=1:4
               app.machineState(i)=1000;
           end
           app.machineState(5)=100;
           app.Lamp.Color='g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.EspressoButton = uibutton(app.UIFigure, 'push');
            app.EspressoButton.ButtonPushedFcn = createCallbackFcn(app, @EspressoButtonPushed, true);
            app.EspressoButton.Text = 'Espresso             1.00 BAM';
            app.CappuccinoButton = uibutton(app.UIFigure, 'push');
            app.CappuccinoButton.ButtonPushedFcn = createCallbackFcn(app, @Cappuchino, true);
            app.CappuccinoButton.Text = 'Cappuccino         2.00 BAM';
            app.MacchiatoButton = uibutton(app.UIFigure, 'push');
            app.MacchiatoButton.ButtonPushedFcn = createCallbackFcn(app, @macchiato, true);
            app.MacchiatoButton.Text = 'Macchiato           2.00 BAM ';
            app.FlatWhiteButton = uibutton(app.UIFigure, 'push');
            app.FlatWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @flatwhite, true);
            app.FlatWhiteButton.Text = 'Flat White           3.00 BAM';
            app.CortadoButton = uibutton(app.UIFigure, 'push');
            app.CortadoButton.ButtonPushedFcn = createCallbackFcn(app, @cortado, true);
            app.CortadoButton.Text = 'Cortado              3.00 BAM';
            app.DoppioButton = uibutton(app.UIFigure, 'push');
            app.DoppioButton.ButtonPushedFcn = createCallbackFcn(app, @doppio, true);
            app.DoppioButton.Text = 'Doppio                 2.50 BAM';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirm, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ButtonNoSugar = uibutton(app.UIFigure, 'push');
            app.ButtonNoSugar.ButtonPushedFcn = createCallbackFcn(app, @noSugar, true);
            app.ButtonNoSugar.Text = '-';
            app.ButtonSugar = uibutton(app.UIFigure, 'push');
            app.ButtonSugar.ButtonPushedFcn = createCallbackFcn(app, @addSugar, true);
            app.ButtonSugar.Text = '+';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Sugar';
            app.Lamp = uilamp(app.UIFigure);
            app.KF20 = uiimage(app.UIFigure);
            app.KF20.ImageClickedFcn = createCallbackFcn(app, @kf20, true);
            app.KF20.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '20KF.png');
            app.KM5 = uiimage(app.UIFigure);
            app.KM5.ImageClickedFcn = createCallbackFcn(app, @km5, true);
            app.KM5.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '5KM.png');
            app.KM2 = uiimage(app.UIFigure);
            app.KM2.ImageClickedFcn = createCallbackFcn(app, @km2, true);
            app.KM2.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '2KM.png');
            app.KM1 = uiimage(app.UIFigure);
            app.KM1.ImageClickedFcn = createCallbackFcn(app, @km1, true);
            app.KM1.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '1KM.png');
            app.KF50 = uiimage(app.UIFigure);
            app.KF50.ImageClickedFcn = createCallbackFcn(app, @kf50, true);
            app.KF50.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '50KF.png');
            app.KF10 = uiimage(app.UIFigure);
            app.KF10.ImageClickedFcn = createCallbackFcn(app, @kf10, true);
            app.KF10.ImageSource = fullfile(pathToMLAPP, 'moneyImages', '10KF.png');
            app.money = uieditfield(app.UIFigure, 'numeric');
            app.Frame = uiimage(app.UIFigure);
            app.Frame.ImageSource = fullfile(pathToMLAPP, 'coffeeImages', 'frame.jpg');
            app.Welcome = uiimage(app.UIFigure);
            app.Welcome.ImageSource = fullfile(pathToMLAPP, 'coffeeImages', 'coffee.gif');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Welcome!';
            app.change = uieditfield(app.UIFigure, 'numeric');
            app.sugarCount = uieditfield(app.UIFigure, 'numeric');
            app.maintenanceButton = uibutton(app.UIFigure, 'push');
            app.maintenanceButton.ButtonPushedFcn = createCallbackFcn(app, @maintenance, true);
            app.maintenanceButton.Visible = 'off';
            app.maintenanceButton.Text = 'MAINTENANCE';
            app.coffeeSlot2 = uiimage(app.UIFigure);
            app.coffeeSlot2.ImageSource = fullfile(pathToMLAPP, 'coffeeVideo', 'coffeeDefaultFrame2.png');
            app.coffeeSlot = uiimage(app.UIFigure);
            app.coffeeSlot.ImageSource = fullfile(pathToMLAPP, 'coffeeVideo', 'coffeeStartFrame2-fotor-20231223213256.png');
            app.Frame_2 = uiimage(app.UIFigure);
            app.Frame_2.ImageSource = fullfile(pathToMLAPP, 'coffeeImages', 'frame.jpg');
            app.inputSlot = uibutton(app.UIFigure, 'push');
            app.inputSlot.Icon = fullfile(pathToMLAPP, 'moneyImages', 'moneyInput.gif');
            app.inputSlot.Text = '';
            app.outputSlot = uibutton(app.UIFigure, 'push');
            app.outputSlot.Icon = fullfile(pathToMLAPP, 'moneyImages', 'changeOutput.gif');
            app.outputSlot.Text = '';
            app.moneyText = uieditfield(app.UIFigure, 'text');
            app.moneyText.Value = 'Money';
            app.changeText = uieditfield(app.UIFigure, 'text');
            app.changeText.Value = 'Change';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outline1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end